classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Button                  matlab.ui.control.Button
        original                matlab.ui.control.UIAxes
        Label                   matlab.ui.control.Label
        frame                   matlab.ui.control.NumericEditField
        Label_2                 matlab.ui.control.Label
        height                  matlab.ui.control.NumericEditField
        Label_3                 matlab.ui.control.Label
        left_frame              matlab.ui.control.NumericEditField
        width                   matlab.ui.control.NumericEditField
        result_1                matlab.ui.control.UIAxes
        result_2                matlab.ui.control.UIAxes
        Button_2                matlab.ui.control.Button
        result_3                matlab.ui.control.UIAxes
        KEditFieldLabel         matlab.ui.control.Label
        K                       matlab.ui.control.NumericEditField
        blurredPSNRLabel        matlab.ui.control.Label
        blurred_PSNR            matlab.ui.control.NumericEditField
        ISNRLabel               matlab.ui.control.Label
        ISNR                    matlab.ui.control.NumericEditField
        deblurredPSNRLabel      matlab.ui.control.Label
        reverse_PSNR            matlab.ui.control.NumericEditField
        ISNRLabel_2             matlab.ui.control.Label
        ISNR_2                  matlab.ui.control.NumericEditField
        deblurredPSNRLabel_2    matlab.ui.control.Label
        reverse_threshold_PSNR  matlab.ui.control.NumericEditField
        ISNRLabel_3             matlab.ui.control.Label
        ISNR_3                  matlab.ui.control.NumericEditField
        deblurredPSNRLabel_3    matlab.ui.control.Label
        Wiener_PSNR             matlab.ui.control.NumericEditField
        result_4                matlab.ui.control.UIAxes
        result_5                matlab.ui.control.UIAxes
        ISNRLabel_4             matlab.ui.control.Label
        ISNR_4                  matlab.ui.control.NumericEditField
        deblurredPSNRLabel_4    matlab.ui.control.Label
        Lucy_PSNR               matlab.ui.control.NumericEditField
        Stop                    matlab.ui.control.Button
        ISNRLabel_5             matlab.ui.control.Label
        ISNR_5                  matlab.ui.control.NumericEditField
        deblurredPSNRLabel_5    matlab.ui.control.Label
        binary_PSNR             matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function results = reverse(app,kernal,A,threshold,row,col,has_threshold,is_gray)
            
            H = psf2otf(kernal,size(A));
            for i=1:row
                for j=1:col
                    if(~is_gray)
                        if (has_threshold==0||H(i,j,1)>threshold)
                            H(i,j,1) = 1/H(i,j,1);
                            H(i,j,2) = 1/H(i,j,2);
                            H(i,j,3) = 1/H(i,j,3);
                        if(has_threshold==1&&H(i,j,1)<threshold)
                            H(i,j,1) = 0;
                            H(i,j,2) = 0;
                            H(i,j,3) = 0;
                        end
                        end
                    else
                        if (has_threshold==0||H(i,j)>threshold)
                            H(i,j) = 1/H(i,j);
                        end
                    end
                end
            end
            results = ifftn(H .* fftn(A));
            results = real(results);
            if(is_gray) 
                results = 150*results;
            end
            results = uint8(results);
        end
        
        function file = choose_file(app)
            [filename,pathname] = uigetfile({'*'},'选择文件');
            if (isequal(filename,0)||isequal(pathname,0))
                errordlg('未选择文件','错误');
            else
                file = fopen([pathname,filename],'r');
            end
        end
        
        function results = wiener_filter(app,kernal,A,k,gray)
            H = psf2otf(kernal,size(A));
            G = conj(H)./(abs(H).^2+k);
            results = ifftn(G .* fftn(A));
            results = real(results);        
            results = uint8(results);
            if(gray)
                results = results*150;
            end
        end
        
        function results = filter_gray(app,kernal,A,row,col)
            results = A;
            bias = 4;
            temp = zeros(row+8,col+8);
            temp(5:row+4,5:col+4) = A;
            A = temp;
            for i = 1:row
                for j=1:col
                    temp = A(i-4+bias:i+4+bias,j-4+bias:j+4+bias).*kernal;
                    results(i,j) = (sum(temp(:)))/81;
                end
            end
        end
        
        function results = advanced_deblurred(app,row,col,A,psf)                                   
              LIM = ceil(size(psf,1)/2);
              WEIGHT = zeros(row,col);                         
              WEIGHT(LIM+1:end-LIM,LIM+1:end-LIM)=1;           
               
              results(:,:,1) = deconvlucy(A(:,:,1),psf,10,0,WEIGHT);  
              results(:,:,2) = deconvlucy(A(:,:,2),psf,10,0,WEIGHT);
              results(:,:,3) = deconvlucy(A(:,:,3),psf,10,0,WEIGHT);
              results = uint8(results);
        end
        
        function results = binary_method(app,row,col,A,psf)
            p = [0 -1 0;-1 4 -1;0 -1 0];
            P = psf2otf(p,[row,col]);

            Pf = psf2otf(psf,[row,col]);
            gama = 0.001;
            If = fft2(A);

            numerator = conj(Pf);
            denominator = Pf.^2 + gama*(P.^2);

            results = ifft2( numerator.*If./ denominator );
            results = uint8(results);
        end
    end
    

    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            original = choose_file(app);
            global img;
            img = choose_file(app);
            row = app.width.Value;
            col = app.height.Value; 
            frames = app.frame.Value;
            app.left_frame.Value = app.frame.Value;
            psf= [0.0000 0.0000 0.0001 0.0003 0.0004 0.0003 0.0001 0.0000 0.0000; 
                  0.0000 0.0002	0.0012 0.0034 0.0049 0.0034 0.0012 0.0002 0.0000;
                  0.0001 0.0012	0.0069 0.0195 0.0276 0.0195 0.0069 0.0012 0.0001;
                  0.0003 0.0034	0.0195 0.0552 0.0781 0.0552 0.0195 0.0034 0.0003;
                  0.0004 0.0049 0.0276 0.0781 0.1105 0.0781 0.0276 0.0049 0.0004; 
                  0.0003 0.0034 0.0195 0.0552 0.0781 0.0552 0.0195 0.0034 0.0003; 
                  0.0001 0.0012	0.0069 0.0195 0.0276 0.0195 0.0069 0.0012 0.0001;
                  0.0000 0.0002	0.0012 0.0034 0.0049 0.0034 0.0012 0.0002 0.0000;
                  0.0000 0.0000 0.0001 0.0003 0.0004 0.0003 0.0001 0.0000 0.0000];
            blurred_PSNR = 0.0;
            reverse_PSNR = 0.0;
            reverse_threshold_PSNR = 0.0;
            Wiener_PSNR = 0.0;
            binary_PSNR = 0.0;
            Lucy_PSNR = 0.0;
            for i = 1:frames
                Yo = (fread(original,[row,col]))';
                Uo = (fread(original,[row/2,col/2]))';
                Vo = (fread(original,[row/2,col/2]))';
                original_frame = yuv420torgb(Yo,Uo,Vo);
                
                Y1 = (fread(img,[row,col]))';
                U1 = (fread(img,[row/2,col/2]))';
                V1 = (fread(img,[row/2,col/2]))';
                A = yuv420torgb(Y1,U1,V1);
                blurred_PSNR = blurred_PSNR+psnr(A,original_frame);
                imshow(A,'Parent',app.original);
                A = double(A);
                re = reverse(app,psf,A,0.05,col,row,0,0);
                imshow(re,'Parent',app.result_1);
                reverse_PSNR = reverse_PSNR+psnr(re,original_frame);
                re2 = reverse(app,psf,A,0.05,col,row,1,0);
                imshow(re2,'Parent',app.result_2);
                reverse_threshold_PSNR = reverse_threshold_PSNR+psnr(re2,original_frame);
                Wiener = wiener_filter(app,psf,A,app.K.Value,0);
                imshow(Wiener,'Parent',app.result_3);
                Wiener_PSNR = Wiener_PSNR+psnr(Wiener,original_frame);
                
                advanced = advanced_deblurred(app,col,row,A,psf);
                Lucy_PSNR = Lucy_PSNR +psnr(advanced,original_frame);
                imshow(advanced,'Parent',app.result_4);
                
                binary = binary_method(app,col,row,A,psf);
                binary_PSNR = binary_PSNR + psnr(binary,original_frame);
                imshow(advanced,'Parent',app.result_5);
                pause(0.002)
                app.left_frame.Value = app.left_frame.Value-1;
                app.blurred_PSNR.Value = blurred_PSNR/i;
                app.reverse_PSNR.Value = reverse_PSNR/i;
                app.reverse_threshold_PSNR.Value = reverse_threshold_PSNR/i;
                app.Wiener_PSNR.Value = Wiener_PSNR/i;
                app.binary_PSNR.Value = binary_PSNR/i;
                app.Lucy_PSNR.Value = Lucy_PSNR/i;
                app.ISNR.Value = app.blurred_PSNR.Value-app.reverse_PSNR.Value;
                app.ISNR_2.Value = app.blurred_PSNR.Value-app.reverse_threshold_PSNR.Value;
                app.ISNR_3.Value = app.blurred_PSNR.Value-app.Wiener_PSNR.Value;
                app.ISNR_4.Value = app.blurred_PSNR.Value-app.Lucy_PSNR.Value;
                app.ISNR_5.Value = app.blurred_PSNR.Value-app.binary_PSNR.Value;
                if (app.left_frame.Value<=0)
                    break
                end
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            original = choose_file(app);
            global img;
            img = choose_file(app);
            row = app.width.Value;
            col = app.height.Value; 
            frames = app.frame.Value;
            app.left_frame.Value = app.frame.Value;
            kernal =[0.0000 0.0000 0.0000 0.0000 0.0000 0.0000 0.0000 0.0145 0.0000; 
                   0.0000 0.0000 0.0000 0.0000 0.0000 0.0000 0.0262 0.0896 0.0145;
                   0.0000 0.0000 0.0000 0.0000 0.0000 0.0262 0.0896 0.0262 0.0000;
                   0.0000 0.0000 0.0000 0.0000 0.0262 0.0896 0.0262 0.0000 0.0000;
                   0.0000 0.0000 0.0000 0.0262 0.0896 0.0262 0.0000 0.0000 0.0000; 
                   0.0000 0.0000 0.0262 0.0896 0.0262 0.0000 0.0000 0.0000 0.0000;
                   0.0000 0.0262 0.0896 0.0262 0.0000 0.0000 0.0000 0.0000 0.0000;
                   0.0145 0.0896 0.0262 0.0000 0.0000 0.0000 0.0000 0.0000 0.0000;
                   0.0000 0.0145 0.0000 0.0000 0.0000 0.0000 0.0000 0.0000 0.0000;];
            blurred_PSNR = 0.0;
            reverse_PSNR = 0.0;
            reverse_threshold_PSNR = 0.0;
            Wiener_PSNR = 0.0;
            binary_PSNR = 0.0;
            Lucy_PSNR = 0.0;
           for i = 1:frames
               Yo = (fread(original,[row,col]))';
                Uo = (fread(original,[row/2,col/2]))';
                Vo = (fread(original,[row/2,col/2]))';
                original_frame = yuv420torgb(Yo,Uo,Vo);
                
                Y1 = (fread(img,[row,col]))';
                U1 = (fread(img,[row/2,col/2]))';
                V1 = (fread(img,[row/2,col/2]))';
                A = yuv420torgb(Y1,U1,V1);
                imshow(A,'Parent',app.original);
                blurred_PSNR = blurred_PSNR+psnr(A,original_frame);
                A = double(A);
                re = reverse(app,kernal,A,0.05,col,row,0,0);
                reverse_PSNR = reverse_PSNR+psnr(re,original_frame);
                imshow(re,'Parent',app.result_1);
                re2 = reverse(app,kernal,A,0.2,col,row,1,0);
                reverse_threshold_PSNR = reverse_threshold_PSNR+psnr(re2,original_frame);
                imshow(re2,'Parent',app.result_2);
                Wiener = wiener_filter(app,kernal,A,app.K.Value,0);
                Wiener_PSNR = Wiener_PSNR+psnr(Wiener,original_frame);
                imshow(Wiener,'Parent',app.result_3);
                
                advanced = advanced_deblurred(app,col,row,A,kernal);
                Lucy_PSNR = Lucy_PSNR +psnr(advanced,original_frame);
                imshow(advanced,'Parent',app.result_4);
                
                binary = binary_method(app,col,row,A,kernal);
                binary_PSNR = binary_PSNR + psnr(binary,original_frame);
                imshow(advanced,'Parent',app.result_5);
                pause(0.002)
                app.left_frame.Value = app.left_frame.Value-1;
                app.blurred_PSNR.Value = blurred_PSNR/i;
                app.reverse_PSNR.Value = reverse_PSNR/i;
                app.reverse_threshold_PSNR.Value = reverse_threshold_PSNR/i;
                app.Wiener_PSNR.Value = Wiener_PSNR/i;
                app.binary_PSNR.Value = binary_PSNR/i;
                app.Lucy_PSNR.Value = Lucy_PSNR/i;
                app.ISNR.Value = app.blurred_PSNR.Value-app.reverse_PSNR.Value;
                app.ISNR_2.Value = app.blurred_PSNR.Value-app.reverse_threshold_PSNR.Value;
                app.ISNR_3.Value = app.blurred_PSNR.Value-app.Wiener_PSNR.Value;
                app.ISNR_4.Value = app.blurred_PSNR.Value-app.Lucy_PSNR.Value;
                app.ISNR_5.Value = app.blurred_PSNR.Value-app.binary_PSNR.Value;
                if (app.left_frame.Value<=0)
                    break
                end
            end
        end

        % Callback function
        function cameramanButtonPushed(app, event)
            I = im2double(imread('cameraman.tif'));
            kernal =[0.0000 0.0000 0.0000 0.0000 0.0000 0.0000 0.0000 0.0145 0.0000; 
                   0.0000 0.0000 0.0000 0.0000 0.0000 0.0000 0.0262 0.0896 0.0145;
                   0.0000 0.0000 0.0000 0.0000 0.0000 0.0262 0.0896 0.0262 0.0000;
                   0.0000 0.0000 0.0000 0.0000 0.0262 0.0896 0.0262 0.0000 0.0000;
                   0.0000 0.0000 0.0000 0.0262 0.0896 0.0262 0.0000 0.0000 0.0000; 
                   0.0000 0.0000 0.0262 0.0896 0.0262 0.0000 0.0000 0.0000 0.0000;
                   0.0000 0.0262 0.0896 0.0262 0.0000 0.0000 0.0000 0.0000 0.0000;
                   0.0145 0.0896 0.0262 0.0000 0.0000 0.0000 0.0000 0.0000 0.0000;
                   0.0000 0.0145 0.0000 0.0000 0.0000 0.0000 0.0000 0.0000 0.0000;];
            I = I*100;
            blurred_PSNR = 0.0;
            reverse_PSNR = 0.0;
            reverse_threshold_PSNR = 0.0;
            Wiener_PSNR = 0.0;
            binary_PSNR = 0.0;
            Lucy_PSNR = 0.0;
            blurred = filter_gray(app,kernal,I,256,256);
            imshow(blurred,'Parent',app.original);
            [col,row] = size(blurred);
            re1 = reverse(app,kernal,blurred,0,col,row,0,1);
            imshow(re1,'Parent',app.result_1);
            re2 = reverse(app,kernal,blurred,0.2,col,row,1,1);
            imshow(re2,'Parent',app.result_2);
            wiener = wiener_filter(app,kernal,blurred,app.K.Value,1);
            imshow(wiener,'Parent',app.result_3);
            
            advanced = advanced_deblurred(app,col,row,blurred,kernal);
            Lucy_PSNR = Lucy_PSNR +psnr(advanced,original_frame);
            imshow(advanced,'Parent',app.result_4);
               
            binary = binary_method(app,col,row,blurred,kernal);
            binary_PSNR = binary_PSNR + psnr(binary,original_frame);
            imshow(advanced,'Parent',app.result_5);
            
            app.blurred_PSNR.Value = psnr(blurred,I);
            app.reverse_PSNR.Value = psnr(re1,I);
            app.reverse_threshold_PSNR.Value = psnr(re2,I);
            app.Wiener_PSNR.Value = psnr(wiener,I);
            app.binary_PSNR.Value = psnr(binary,I);
            app.Lucy_PSNR.Value = psnr(advanced,I);
            app.ISNR.Value = app.blurred_PSNR.Value-app.reverse_PSNR.Value;
            app.ISNR_2.Value = app.blurred_PSNR.Value-app.reverse_threshold_PSNR.Value;
            app.ISNR_3.Value = app.blurred_PSNR.Value-app.Wiener_PSNR.Value;
            app.ISNR_4.Value = app.blurred_PSNR.Value-app.Lucy_PSNR.Value;
            app.ISNR_5.Value = app.blurred_PSNR.Value-app.binary_PSNR.Value;
                
        end

        % Button pushed function: Stop
        function StopPushed(app, event)
            app.left_frame.Value = 0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '选择高斯模糊文件';
            app.original = uiaxes(app.UIFigure);
            title(app.original, '原始')
            xlabel(app.original, '')
            ylabel(app.original, '')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '帧数';
            app.frame = uieditfield(app.UIFigure, 'numeric');
            app.frame.Value = 90;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '尺寸';
            app.height = uieditfield(app.UIFigure, 'numeric');
            app.height.Value = 288;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '剩余帧数';
            app.left_frame = uieditfield(app.UIFigure, 'numeric');
            app.width = uieditfield(app.UIFigure, 'numeric');
            app.width.Value = 352;
            app.result_1 = uiaxes(app.UIFigure);
            title(app.result_1, '直接逆滤波结果')
            xlabel(app.result_1, '')
            ylabel(app.result_1, '')
            app.result_2 = uiaxes(app.UIFigure);
            title(app.result_2, '阈值逆滤波')
            xlabel(app.result_2, '')
            ylabel(app.result_2, '')
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '选择运动模糊文件';
            app.result_3 = uiaxes(app.UIFigure);
            title(app.result_3, '维纳滤波')
            xlabel(app.result_3, '')
            ylabel(app.result_3, '')
            app.KEditFieldLabel = uilabel(app.UIFigure);
            app.KEditFieldLabel.Text = '维纳滤波K值';
            app.K = uieditfield(app.UIFigure, 'numeric');
            app.K.Value = 0.02;
            app.blurredPSNRLabel = uilabel(app.UIFigure);
            app.blurredPSNRLabel.Text = 'blurred PSNR';
            app.blurred_PSNR = uieditfield(app.UIFigure, 'numeric');
            app.ISNRLabel = uilabel(app.UIFigure);
            app.ISNRLabel.Text = 'ISNR';
            app.ISNR = uieditfield(app.UIFigure, 'numeric');
            app.deblurredPSNRLabel = uilabel(app.UIFigure);
            app.deblurredPSNRLabel.Text = 'deblurred PSNR';
            app.reverse_PSNR = uieditfield(app.UIFigure, 'numeric');
            app.ISNRLabel_2 = uilabel(app.UIFigure);
            app.ISNRLabel_2.Text = 'ISNR';
            app.ISNR_2 = uieditfield(app.UIFigure, 'numeric');
            app.deblurredPSNRLabel_2 = uilabel(app.UIFigure);
            app.deblurredPSNRLabel_2.Text = 'deblurred PSNR';
            app.reverse_threshold_PSNR = uieditfield(app.UIFigure, 'numeric');
            app.ISNRLabel_3 = uilabel(app.UIFigure);
            app.ISNRLabel_3.Text = 'ISNR';
            app.ISNR_3 = uieditfield(app.UIFigure, 'numeric');
            app.deblurredPSNRLabel_3 = uilabel(app.UIFigure);
            app.deblurredPSNRLabel_3.Text = 'deblurred PSNR';
            app.Wiener_PSNR = uieditfield(app.UIFigure, 'numeric');
            app.result_4 = uiaxes(app.UIFigure);
            title(app.result_4, 'Lucy-Richardson迭代')
            xlabel(app.result_4, '')
            ylabel(app.result_4, '')
            app.result_5 = uiaxes(app.UIFigure);
            title(app.result_5, '最小二乘方滤波')
            xlabel(app.result_5, '')
            ylabel(app.result_5, '')
            app.ISNRLabel_4 = uilabel(app.UIFigure);
            app.ISNRLabel_4.Text = 'ISNR';
            app.ISNR_4 = uieditfield(app.UIFigure, 'numeric');
            app.deblurredPSNRLabel_4 = uilabel(app.UIFigure);
            app.deblurredPSNRLabel_4.Text = 'deblurred PSNR';
            app.Lucy_PSNR = uieditfield(app.UIFigure, 'numeric');
            app.Stop = uibutton(app.UIFigure, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @StopPushed, true);
            app.Stop.Text = '终止计算';
            app.ISNRLabel_5 = uilabel(app.UIFigure);
            app.ISNRLabel_5.Text = 'ISNR';
            app.ISNR_5 = uieditfield(app.UIFigure, 'numeric');
            app.deblurredPSNRLabel_5 = uilabel(app.UIFigure);
            app.deblurredPSNRLabel_5.Text = 'deblurred PSNR';
            app.binary_PSNR = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end